classdef Analyze_X < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ImportPlotButton          matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        RegionTypeButton          matlab.ui.control.Button
        SalesBreakdownButton      matlab.ui.control.Button
        ClientTypeAnalysisButton  matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClientTypeAnalysisButton
        function ClientTypeAnalysisButtonPushed(app, event)

        try
        fileName = 'Book2.xlsx';
        sheetName = 'Sheet3';

        data = readtable(fileName, 'Sheet', sheetName);

        salesData = data.Sales;
        ClientTypeData = categorical(data.ClientType);

        [uniqueRegionTypes, ~, groupIdx] = unique(ClientTypeData); 
        salesByRegionType = accumarray(groupIdx, salesData, [], @sum);

        % Plot 
        figure;
        bar(salesByRegionType, 'stacked');
        set(gca, 'XTick', 1:length(uniqueRegionTypes));
        set(gca, 'XTickLabel', cellstr(uniqueRegionTypes));
        xlabel('Client Type');
        ylabel('Total Sales');

        disp('REGION analysis is done.');
    catch ME
        disp(['Error: ' ME.message]);
        end
        
        end

        % Button pushed function: SalesBreakdownButton
        function SalesBreakdownButtonPushed(app, event)
  fileName= 'Book2.xlsx';
  sheetName= 'Sheet4';
  data= readtable(fileName, 'Sheet', sheetName);
  salesData= data.Sales;
  productData= data.Product;
  [uniqueProducts, ~ , groupIdx] = unique(productData);
  x= accumarray(groupIdx, salesData, [], @sum);
  
  totalSales= sum(x);
  y= (x/ totalSales) * 100;
  figure;
  pie(y, cellstr(uniqueProducts));
  title('Sales Breakdown By Products');
  disp('Products Chart is made.');
        end

        % Button pushed function: RegionTypeButton
        function RegionTypeButtonPushed(app, event)
      try
          fileName = 'Book2.xlsx';
          sheetName = 'Sheet5';
data = readtable(fileName,'Sheet',sheetName);
salesData = data.Sales;
R= categorical(data.Region);

[uniqueRegions, ~ , groupIdx]= unique(R);
salesByRegion= accumarray(groupIdx, salesData, [], @sum);

%Plot
figure;
bar(salesByRegion, 'FaceColor','green');
set(gca, 'XTick',1:length(uniqueRegions));
set(gca, 'XTickLabel', cellstr(uniqueRegions));
xlabel('Regions');
ylabel('Total Sales');
title('Sales by Region');
disp("SalesByRegion Chart is created.");

      catch ME
          disp(['Error:', ME.message])
      end
        end

        % Button pushed function: ImportPlotButton
        function ImportPlotButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select an Excel File');
            if isequal(file,0)
                return;
            end
            %Read Excel file into table
            t = readtable(fullfile(path,file));

            %Display table
            app.UITable.Data = t;

          %Make sure date get its data from table date
          if ismember("Date",t.Properties.VariableNames) && ismember("Sales",t.Properties.VariableNames)
              t.Date = datetime(t.Date,'Format','yyyy-MM-dd');
              t= sortrows(t,"Date");

              %Plot
              x= t.Date;
              y= t.Sales;

              plot(app.UIAxes,x,y, '-o');
              title(app.UIAxes,'Sales Trend Over Time');
              xlabel(app.UIAxes,'Date');
              ylabel(app.UIAxes,'Sales');
              grid(app.UIAxes,'on');
          else
              uialert(app.UIFigure,'Wrong File','Missing Date and Sales Columns')
          end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sales Trend Over Time')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Sales')
            zlabel(app.UIAxes, 'Z')
            app.ClientTypeAnalysisButton = uibutton(app.UIFigure, 'push');
            app.ClientTypeAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ClientTypeAnalysisButtonPushed, true);
            app.ClientTypeAnalysisButton.Text = 'ClientType Analysis';
            app.SalesBreakdownButton = uibutton(app.UIFigure, 'push');
            app.SalesBreakdownButton.ButtonPushedFcn = createCallbackFcn(app, @SalesBreakdownButtonPushed, true);
            app.SalesBreakdownButton.Text = 'Sales Breakdown';
            app.RegionTypeButton = uibutton(app.UIFigure, 'push');
            app.RegionTypeButton.ButtonPushedFcn = createCallbackFcn(app, @RegionTypeButtonPushed, true);
            app.RegionTypeButton.Text = 'RegionType';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Date'; 'Client Type'; 'Product'; 'Region'; 'Sales'};
            app.UITable.RowName = {};
            app.ImportPlotButton = uibutton(app.UIFigure, 'push');
            app.ImportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPlotButtonPushed, true);
            app.ImportPlotButton.Text = 'Import & Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analyze_X

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end